classdef SI_data_logger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProjectTab                      matlab.ui.container.Tab
        DescriptionTextArea_2Label      matlab.ui.control.Label
        Project_description             matlab.ui.control.TextArea
        ProjecthomedirectoryEditFieldLabel  matlab.ui.control.Label
        Project_homedirectoryEditField  matlab.ui.control.EditField
        SelectButton                    matlab.ui.control.Button
        LabTextAreaLabel                matlab.ui.control.Label
        LabTextArea                     matlab.ui.control.TextArea
        InstitutionTextAreaLabel        matlab.ui.control.Label
        InstitutionTextArea             matlab.ui.control.TextArea
        ExperimenterTextAreaLabel       matlab.ui.control.Label
        ExperimenterTextArea            matlab.ui.control.TextArea
        ProtocolTextAreaLabel           matlab.ui.control.Label
        ProtocolTextArea                matlab.ui.control.TextArea
        ProjectNameTextAreaLabel        matlab.ui.control.Label
        ProjectNameTextArea             matlab.ui.control.TextArea
        LoadButton                      matlab.ui.control.Button
        CreateButton_2                  matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        SubjectTab                      matlab.ui.container.Tab
        AnimalIDTextAreaLabel           matlab.ui.control.Label
        AnimalIDTextArea                matlab.ui.control.TextArea
        SpeciesDropDownLabel            matlab.ui.control.Label
        SpeciesDropDown                 matlab.ui.control.DropDown
        GenotypeDropDownLabel           matlab.ui.control.Label
        GenotypeDropDown                matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        InjectiondateDatePickerLabel    matlab.ui.control.Label
        InjectiondateDatePicker         matlab.ui.control.DatePicker
        WeightgrSpinnerLabel            matlab.ui.control.Label
        WeightgrSpinner                 matlab.ui.control.Spinner
        DescrptionLabel                 matlab.ui.control.Label
        subject_description             matlab.ui.control.TextArea
        SurgeryDropDownLabel            matlab.ui.control.Label
        SurgeryDropDown                 matlab.ui.control.DropDown
        VirusDropDownLabel              matlab.ui.control.Label
        VirusDropDown                   matlab.ui.control.DropDown
        HemisphereDropDownLabel         matlab.ui.control.Label
        HemisphereDropDown              matlab.ui.control.DropDown
        DOBDatePicker_2Label            matlab.ui.control.Label
        DOBDatePicker_2                 matlab.ui.control.DatePicker
        SurgerydateDatePickerLabel      matlab.ui.control.Label
        SurgerydateDatePicker           matlab.ui.control.DatePicker
        AddSubjectButton                matlab.ui.control.Button
        SessionTab                      matlab.ui.container.Tab
        SessionIDTextAreaLabel          matlab.ui.control.Label
        SessionIDTextArea               matlab.ui.control.TextArea
        FOVSpinnerLabel                 matlab.ui.control.Label
        FOVSpinner                      matlab.ui.control.Spinner
        DescriptionSectionTextAreaLabel  matlab.ui.control.Label
        SessionDescription              matlab.ui.control.TextArea
        TimePointSpinnerLabel           matlab.ui.control.Label
        TimePointSpinner                matlab.ui.control.Spinner
        ConditionDropDownLabel          matlab.ui.control.Label
        ConditionDropDown               matlab.ui.control.DropDown
        InitializeButton                matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        SessionsubjectDropDownLabel     matlab.ui.control.Label
        SessionsubjectDropDown          matlab.ui.control.DropDown
        SessionsubjectweightSpinnerLabel  matlab.ui.control.Label
        SessionsubjectweightSpinner     matlab.ui.control.Spinner
        AcquireTab                      matlab.ui.container.Tab
        GrabButton                      matlab.ui.control.Button
        AcquireanalogdataCheckBox       matlab.ui.control.CheckBox
        DatatypeDropDownLabel           matlab.ui.control.Label
        DatatypeDropDown                matlab.ui.control.DropDown
        DescriptionAcq                  matlab.ui.control.Label
        Session_id_2                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        PROJECT_ROOT_DIR
        PROJECT_ID
        SESSION_ID
        SUBJECT_ID
        FOV
        CONDITION
        PATH_TO_SESSION %session root folder
        PATH_TO_SESSION_DATA_OPHYS %optic physiology data gets written here
        PATH_TO_SESSION_DATA_ANAT %anatomy data gets written here       
        PATH_TO_SESSION_DERIVATIVES %pynapple needed variables will go here
        TIMEPOINT
        DATA_TYPE
        lamp_off_color = [1 0 0];
        lamp_on_color = [0 1 0];
    end
    
    methods (Access = public)
        
        function get_session_parameters(app)
            app.PROJECT_ROOT_DIR = app.Project_homedirectoryEditField.Value;
            app.SESSION_ID = app.SessionDescription.Value;
            app.TIMEPOINT = app.TimePointSpinner.Value;
            app.CONDITION = app.ConditionDropDown.Value;
        end
        
        function generate_session_id(app)
            app.SUBJECT_ID = app.SessionsubjectDropDown.Value;
            app.FOV = app.FOVSpinner.Value;
            app.SESSION_ID = sprintf('ses-%s-%s-FOV%02d-%s-T%02d',app.SUBJECT_ID,datestr(now,'YYYYmmDD'),...
                app.FOV,app.CONDITION,app.TIMEPOINT);
            app.SessionDescription.Value = app.SESSION_ID;
            app.SessionIDTextArea.Value = app.SESSION_ID;
        end
        
        function init_session(app)
            %setup recording path based on session parameters - Make
            %compliant with [partial] BIDS and thinking of future NWB
            %📂 MyProject
            %└── 📂 sub-A2929
            %       └── 📂 ses-A2929-200711-FOV01-Condition-T01
            %%              └── 📂 ophys %optical physiology (Movies, arbitrary scans and analog data)
            %%              └── 📂 anat  %anatomical stuff (3D stacks, overviews etc)
            %%              └── 📂 derivates
            %%  project.yml or project.xml (supported)
            %%  sessions.csv
            %%  subjects.csv
            
            path_to_session= fullfile(app.PROJECT_ROOT_DIR,cell2mat(app.SESSION_ID));
            app.PATH_TO_SESSION_DATA_OPHYS = fullfile(path_to_session,'ophys');
            app.PATH_TO_SESSION_DATA_ANAT = fullfile(path_to_session,'anat');
            app.PATH_TO_SESSION_DERIVATIVES = fullfile(path_to_session,'derivates');
            try
                %create data structure
                fprintf('\n\tInitializing session directory.\n')
                if ~isfolder(app.PATH_TO_SESSION_DATA_OPHYS);mkdir(app.PATH_TO_SESSION_DATA_OPHYS);end
                if ~isfolder(app.PATH_TO_SESSION_DATA_ANAT);mkdir(app.PATH_TO_SESSION_DATA_ANAT);end
                if ~isfolder(app.PATH_TO_SESSION_DERIVATIVES);mkdir(app.PATH_TO_SESSION_DERIVATIVES);end
            catch
                warndlg('Failed to initialize session data structure')
                return
            end
            toggle_grab_status(app,'on');
            util_log_session(app);
        end
        
        function subject = get_subject(app)
            subject =  struct('id',app.AnimalIDTextArea.Value{1},...
                'species', app.SpeciesDropDown.Value,'genotype',app.GenotypeDropDown.Value,...
                'sex',app.SexDropDown.Value,'weight',...
                app.WeightgrSpinner.Value,'dob',app.DOBDatePicker_2.Value,...
                'surgery',app.SurgeryDropDown.Value,'hemisphere',app.HemisphereDropDown.Value,...
                'surgery_date',app.SurgerydateDatePicker.Value,'Virus',app.VirusDropDown.Value,...
                'virus_injection_date',app.InjectiondateDatePicker.Value,...
                'description',app.subject_description.Value);
        end
        
        function populate_session_subject_dropdown(app,options)
            %options must be cell array
            app.SessionsubjectDropDown.Items = options;
        end
        
        function populate_session_conditions(app,options)
            %options must be cell array
            app.ConditionDropDown.Items(app,options)
        end
        
        function toggle_grab_status(app,status)
            %enable or disable grab button 
            switch status
                case 'on'
                    app.Lamp.Color = app.lamp_on_color;
                    app.GrabButton.Enable = 1;
                case 'off'
                    app.Lamp.Color = app.lamp_off_color;
                    app.GrabButton.Enable = 0;
            end
            
                        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin("base","fprintf('\nStarting data logger')");
            toggle_grab_status(app,'off');%make sure no grab can occur until data structure is set
        end

        % Button pushed function: GrabButton
        function GrabButtonPushed(app, event)
            %check that session tree is ready
            if sum(app.Lamp.Color == app.lamp_off_color) == 3
                warndlg('Must initialize session','Session not ready','non-modal')
                return
            end
            
            if  app.AcquireanalogdataCheckBox.Value
                evalin ('base','data_logger_init_analog_acq');
                %set analog acquisition
            end
            get_session_parameters(app);
            evalin('base','hSI.startGrab')
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            path_to_dir = uigetdir("D:\Data",'Select project root folder');
            app.Project_homedirectoryEditField.Value = path_to_dir;
            app.PROJECT_ROOT_DIR = path_to_dir;
            %check if root directory contains project file
            if isfile(fullfile(app.PROJECT_ROOT_DIR,'project.yml'))
                project_load(app)
            end
            %enable initialize button
            app.InitializeButton.Enable=1;
        end

        % Value changed function: SessionIDTextArea
        function SessionIDTextAreaValueChanged(app, event)
            toggle_grab_status(app,'off')
        end

        % Value changed function: AnimalIDTextArea
        function AnimalIDTextAreaValueChanged(app, event)
            toggle_grab_status(app,'off')
        end

        % Value changed function: FOVSpinner
        function FOVSpinnerValueChanged(app, event)
            toggle_grab_status(app,'off')
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %generate session tree
            generate_session_id(app)
            get_session_parameters(app)
            init_session(app)
        end

        % Button pushed function: LoadButton
        function project_load(app, event)
            %attempt to load project.yml, if successful populate project
            %fields in gui
            %path_to_xml = fullfile(app.PROJECT_ROOT_DIR,'project.xml');
            path_to_yml = fullfile(app.PROJECT_ROOT_DIR,'project.yml');
            if isfile(path_to_yml)
                %project = readstruct(path_to_xml);
                project = yaml.loadFile(path_to_yml,"ConvertToArray", true);
            else
                warndlg (sprintf("No project.yml file in %s",app.PROJECT_ROOT_DIR));
            end
            
            %populate Project tab
            app.PROJECT_ID = project.id;
            app.ProjectNameTextArea.Value = project.id;
            app.LabTextArea.Value = project.lab;
            app.InstitutionTextArea.Value = project.institution;
            app.ExperimenterTextArea.Value = project.experimenter;
            app.Project_description.Value = project.description;
            app.ProtocolTextArea.Value = project.protocol_id;
            
            %populate Session tab (Conditions)
            if isfield(project,'conditions')
                app.ConditionDropDown.Items=project.conditions;
            end
            
            %populate Subject tab
            if isfield(project,'genotype')
                app.GenotypeDropDown.Items=project.genotype;
            end
            
            if isfield(project,'virus')
                app.VirusDropDown.Items=project.virus;
            end
            
            %check if subjects table exits, load and populate fields
            path_to_subject_table = fullfile(app.PROJECT_ROOT_DIR,'subjects.csv');
            if isfile(path_to_subject_table)
                subjects_table = readtable(path_to_subject_table);
                subjects_in_table = unique(subjects_table.id);
                populate_session_subject_dropdown(app,subjects_table.id);
            end
            
        end

        % Button pushed function: CreateButton_2
        function project_create(app, event)
            status = action_project_create(app);
        end

        % Button pushed function: AddSubjectButton
        function add_subject(app, event)
            %keep track of project subjects in table (csv format).
            
            %validate filled mandatory  fields - TO DO
            current_subject_id = app.AnimalIDTextArea.Value;
            if isempty(current_subject_id{1})
                warndlg("Animal ID, species, sex and DOB are mandatory.")
                return
            end
            
            path_to_subject_table = fullfile(app.PROJECT_ROOT_DIR,'subjects.csv');
            if isfile(path_to_subject_table)
                subjects_table = readtable(path_to_subject_table);
                %                 subjects_table.Properties.RowNames = "id";
                subjects_in_table = unique(subjects_table.id);
            else
                subjects_in_table={};
                subjects_table=table();
            end
            
            %check to see if current id already in table
            if any(ismember(subjects_in_table,current_subject_id))
                warndlg('Animal id already exists in table');
                return
            else
                subject = get_subject(app);
                subjects_table = [subjects_table;struct2table(subject,'AsArray',true)];
                writetable(subjects_table,path_to_subject_table)
            end
            populate_session_subject_dropdown(app,subjects_table.id);
        end

        % Value changed function: SessionsubjectDropDown
        function SessionsubjectDropDownValueChanged(app, event)

            toggle_grab_status(app,'off');
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            toggle_grab_status(app,'off');           
        end

        % Value changed function: TimePointSpinner
        function TimePointSpinnerValueChanged(app, event)
            toggle_grab_status(app,'off');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.DescriptionTextArea_2Label = uilabel(app.ProjectTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.Project_description = uitextarea(app.ProjectTab);
            app.Project_description.Tag = '__PROJECT_DESCRIPTION';
            app.Project_description.Tooltip = {'Describe your project  here'};
            app.ProjecthomedirectoryEditFieldLabel = uilabel(app.ProjectTab);
            app.ProjecthomedirectoryEditFieldLabel.Text = 'Project home directory';
            app.Project_homedirectoryEditField = uieditfield(app.ProjectTab, 'text');
            app.Project_homedirectoryEditField.Tooltip = {'All sessions and project db, metadate etc  will be stored under this folder.'};
            app.Project_homedirectoryEditField.Value = 'D:\Data';
            app.SelectButton = uibutton(app.ProjectTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LabTextAreaLabel = uilabel(app.ProjectTab);
            app.LabTextAreaLabel.Text = 'Lab';
            app.LabTextArea = uitextarea(app.ProjectTab);
            app.LabTextArea.Tag = '__ANIMAL_ID';
            app.LabTextArea.Value = {'PBLab'};
            app.InstitutionTextAreaLabel = uilabel(app.ProjectTab);
            app.InstitutionTextAreaLabel.Text = 'Institution';
            app.InstitutionTextArea = uitextarea(app.ProjectTab);
            app.InstitutionTextArea.Tag = '__ANIMAL_ID';
            app.InstitutionTextArea.Value = {'Tel Aviv University'};
            app.ExperimenterTextAreaLabel = uilabel(app.ProjectTab);
            app.ExperimenterTextAreaLabel.Text = 'Experimenter';
            app.ExperimenterTextArea = uitextarea(app.ProjectTab);
            app.ExperimenterTextArea.Tag = '__ANIMAL_ID';
            app.ExperimenterTextArea.Tooltip = {'Enter your name here'};
            app.ExperimenterTextArea.Value = {''; ''};
            app.ProtocolTextAreaLabel = uilabel(app.ProjectTab);
            app.ProtocolTextAreaLabel.Text = 'Protocol #';
            app.ProtocolTextArea = uitextarea(app.ProjectTab);
            app.ProtocolTextArea.Tag = '__ANIMAL_ID';
            app.ProtocolTextArea.Tooltip = {'Enter ethics protocol number'};
            app.ProjectNameTextAreaLabel = uilabel(app.ProjectTab);
            app.ProjectNameTextAreaLabel.Text = 'Project Name';
            app.ProjectNameTextArea = uitextarea(app.ProjectTab);
            app.ProjectNameTextArea.Tag = '__ANIMAL_ID';
            app.ProjectNameTextArea.Tooltip = {'Project name'};
            app.LoadButton = uibutton(app.ProjectTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @project_load, true);
            app.LoadButton.Text = 'Load';
            app.CreateButton_2 = uibutton(app.ProjectTab, 'push');
            app.CreateButton_2.ButtonPushedFcn = createCallbackFcn(app, @project_create, true);
            app.CreateButton_2.Text = 'Create';
            app.UpdateButton = uibutton(app.ProjectTab, 'push');
            app.UpdateButton.Text = 'Update';
            app.SubjectTab = uitab(app.TabGroup);
            app.SubjectTab.Title = 'Subject';
            app.AnimalIDTextAreaLabel = uilabel(app.SubjectTab);
            app.AnimalIDTextAreaLabel.Text = 'Animal  ID';
            app.AnimalIDTextArea = uitextarea(app.SubjectTab);
            app.AnimalIDTextArea.ValueChangedFcn = createCallbackFcn(app, @AnimalIDTextAreaValueChanged, true);
            app.AnimalIDTextArea.Tag = '__ANIMAL_ID';
            app.SpeciesDropDownLabel = uilabel(app.SubjectTab);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.SubjectTab);
            app.SpeciesDropDown.Items = {'Mus musculus', 'Etruscan shrew', 'Zebra fish'};
            app.SpeciesDropDown.Value = 'Mus musculus';
            app.GenotypeDropDownLabel = uilabel(app.SubjectTab);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.SubjectTab);
            app.GenotypeDropDown.Items = {'WT', 'Thy1-GCaMP6s', ''};
            app.GenotypeDropDown.Value = 'WT';
            app.SexDropDownLabel = uilabel(app.SubjectTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.SubjectTab);
            app.SexDropDown.Items = {'M', 'F'};
            app.SexDropDown.Value = 'M';
            app.InjectiondateDatePickerLabel = uilabel(app.SubjectTab);
            app.InjectiondateDatePickerLabel.Text = 'Injection  date';
            app.InjectiondateDatePicker = uidatepicker(app.SubjectTab);
            app.WeightgrSpinnerLabel = uilabel(app.SubjectTab);
            app.WeightgrSpinnerLabel.Text = 'Weight (gr)';
            app.WeightgrSpinner = uispinner(app.SubjectTab);
            app.WeightgrSpinner.Step = 0.1;
            app.DescrptionLabel = uilabel(app.SubjectTab);
            app.DescrptionLabel.Text = 'Descrption';
            app.subject_description = uitextarea(app.SubjectTab);
            app.subject_description.Tag = '__PROJECT_DESCRIPTION';
            app.SurgeryDropDownLabel = uilabel(app.SubjectTab);
            app.SurgeryDropDownLabel.Text = 'Surgery';
            app.SurgeryDropDown = uidropdown(app.SubjectTab);
            app.SurgeryDropDown.Items = {'3mm', '5mm', 'PoRTs', 'Crystal_Skull'};
            app.SurgeryDropDown.Value = '3mm';
            app.VirusDropDownLabel = uilabel(app.SubjectTab);
            app.VirusDropDownLabel.Text = 'Virus';
            app.VirusDropDown = uidropdown(app.SubjectTab);
            app.VirusDropDown.Items = {'None', 'php.eb-GCaMP8m', ''};
            app.VirusDropDown.Value = 'None';
            app.HemisphereDropDownLabel = uilabel(app.SubjectTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.SubjectTab);
            app.HemisphereDropDown.Items = {'L', 'R', 'Bi'};
            app.HemisphereDropDown.Value = 'L';
            app.DOBDatePicker_2Label = uilabel(app.SubjectTab);
            app.DOBDatePicker_2Label.Text = 'DOB';
            app.DOBDatePicker_2 = uidatepicker(app.SubjectTab);
            app.SurgerydateDatePickerLabel = uilabel(app.SubjectTab);
            app.SurgerydateDatePickerLabel.Text = 'Surgery date';
            app.SurgerydateDatePicker = uidatepicker(app.SubjectTab);
            app.AddSubjectButton = uibutton(app.SubjectTab, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @add_subject, true);
            app.AddSubjectButton.Text = 'Add';
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.SessionIDTextAreaLabel = uilabel(app.SessionTab);
            app.SessionIDTextAreaLabel.Text = 'Session ID';
            app.SessionIDTextArea = uitextarea(app.SessionTab);
            app.SessionIDTextArea.ValueChangedFcn = createCallbackFcn(app, @SessionIDTextAreaValueChanged, true);
            app.SessionIDTextArea.Tag = '__SESSION_ID';
            app.SessionIDTextArea.Editable = 'off';
            app.FOVSpinnerLabel = uilabel(app.SessionTab);
            app.FOVSpinnerLabel.Text = 'FOV';
            app.FOVSpinner = uispinner(app.SessionTab);
            app.FOVSpinner.Limits = [1 100];
            app.FOVSpinner.RoundFractionalValues = 'on';
            app.FOVSpinner.ValueChangedFcn = createCallbackFcn(app, @FOVSpinnerValueChanged, true);
            app.FOVSpinner.Tag = '__FOV_NUM';
            app.FOVSpinner.Value = 1;
            app.DescriptionSectionTextAreaLabel = uilabel(app.SessionTab);
            app.DescriptionSectionTextAreaLabel.Text = 'Description';
            app.SessionDescription = uitextarea(app.SessionTab);
            app.SessionDescription.Tag = '__ANIMAL_ID';
            app.TimePointSpinnerLabel = uilabel(app.SessionTab);
            app.TimePointSpinnerLabel.Text = 'Time Point';
            app.TimePointSpinner = uispinner(app.SessionTab);
            app.TimePointSpinner.Limits = [0 100];
            app.TimePointSpinner.RoundFractionalValues = 'on';
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @TimePointSpinnerValueChanged, true);
            app.TimePointSpinner.Tag = '__FOV_NUM';
            app.ConditionDropDownLabel = uilabel(app.SessionTab);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.SessionTab);
            app.ConditionDropDown.Items = {'Baseline'};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Value = 'Baseline';
            app.InitializeButton = uibutton(app.SessionTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Tag = '__ACTION_GRAB';
            app.InitializeButton.Enable = 'off';
            app.InitializeButton.Text = 'Initialize';
            app.Lamp = uilamp(app.SessionTab);
            app.SessionsubjectDropDownLabel = uilabel(app.SessionTab);
            app.SessionsubjectDropDownLabel.Text = 'Session subject';
            app.SessionsubjectDropDown = uidropdown(app.SessionTab);
            app.SessionsubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionsubjectDropDownValueChanged, true);
            app.SessionsubjectweightSpinnerLabel = uilabel(app.SessionTab);
            app.SessionsubjectweightSpinnerLabel.Text = 'Session subject weight';
            app.SessionsubjectweightSpinner = uispinner(app.SessionTab);
            app.SessionsubjectweightSpinner.Step = 0.1;
            app.SessionsubjectweightSpinner.Limits = [0 Inf];
            app.AcquireTab = uitab(app.TabGroup);
            app.AcquireTab.Title = 'Acquire';
            app.GrabButton = uibutton(app.AcquireTab, 'push');
            app.GrabButton.ButtonPushedFcn = createCallbackFcn(app, @GrabButtonPushed, true);
            app.GrabButton.Tag = '__ACTION_GRAB';
            app.GrabButton.Text = 'Grab';
            app.AcquireanalogdataCheckBox = uicheckbox(app.AcquireTab);
            app.AcquireanalogdataCheckBox.Tag = '__ACTION_AUTO_SESSION_ID';
            app.AcquireanalogdataCheckBox.Tooltip = {'Create from name, date and time.'};
            app.AcquireanalogdataCheckBox.Text = 'Acquire analog data';
            app.AcquireanalogdataCheckBox.Value = true;
            app.DatatypeDropDownLabel = uilabel(app.AcquireTab);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.AcquireTab);
            app.DatatypeDropDown.Items = {'Movie', 'Stack', 'Arbitrary Scan'};
            app.DatatypeDropDown.Value = 'Movie';
            app.DescriptionAcq = uilabel(app.AcquireTab);
            app.DescriptionAcq.Text = 'Description';
            app.Session_id_2 = uitextarea(app.AcquireTab);
            app.Session_id_2.Tag = '__ANIMAL_ID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SI_data_logger

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end